classdef PXRF2bAxil < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        OpenMenu                       matlab.ui.container.Menu
        InnovXCSVMenu                  matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        SaveAllMenu                    matlab.ui.container.Menu
        NumberofSpectraEditFieldLabel  matlab.ui.control.Label
        NumberofSpectraEditField       matlab.ui.control.NumericEditField
        UISpecTable                    matlab.ui.control.Table
        UIAxes                         matlab.ui.control.UIAxes
        Label                          matlab.ui.control.Label
        XScaleSwitch                   matlab.ui.control.Switch
        Label_2                        matlab.ui.control.Label
        YScaleSwitch                   matlab.ui.control.Switch
        UIInfoTable                    matlab.ui.control.Table
    end

    
    properties (Access = private)
        Spectra          % Structure holding the spectral data
        maxChan          % Maximum number of channels 
        numSpec          % Number of spectra
    end
    
    methods (Access = private)
        
        function InitControls(app)
            app.SaveMenu.Enable = true;
            app.SaveAllMenu.Enable = true;
            app.NumberofSpectraEditField.Value = app.numSpec;
            tableData = cell(app.numSpec,2);
            for n=1:app.numSpec
                tableData{n,1} = app.Spectra(n).TestID;
                tableData{n,2} = false;
            end
            tableData{1,2} = true; % always plot the first spectrum
            app.UISpecTable.Data = tableData;
        end
        
        function success = readInnovXCSV(app,fullFileName)
            % Check if the file is a 16-bit ASCII file
            % Such a file should start with a Bit Order Mark (BOM)
            % for UTF16LE this is 255 254
            success = false;
            app.maxChan = 0;
            fID = fopen(fullFileName,'rb');
            if fID == -1
                disp('Cannot open file');
                disp(fullFileName);
                return;
            end
            success = true;
            isUTF16LE = false;
            bom = fread(fID,2,'uint8');
            if bom(1) == 255 && bom(2) == 254
                isUTF16LE = true;
            end
            fclose(fID);
            % Open the file
            if ~isUTF16LE
                fID = fopen(fullFileName,'rt');
            else
                % Matlab issues a warning when opening an 16-bit ascii file
                % "Warning: The encoding 'UTF-16LE' is not supported."
                % but works correctly, so we turn this warning temporarely off
                warning('off','MATLAB:iofun:UnsupportedEncoding');
                %  and 'n' native encoding UTF16LE
                fID = fopen(fullFileName,'rt','n','UTF16LE');
                % Skip the Byte Order Mark (BOM) at the beginning of the file
                fread(fID,2,'uint8');
            end
            
            % Read and interprete the content of the CSV
            %
            % ---- File looks like this, here only 2 spectra (3 columns) shown
            % Instead of ' separated, may also be tab separated if cvs has been saved
            % with excel
            %
            % TestID,09_10_18 #1,09_10_18 #2,...
            % ExposureNum,1,1,...
            % Offset,-0.017626,-0.017864,...
            % Slope,0.020042,0.020041,...
            % Livetime,13.195500,30.165500,...
            % NumData,2048,2048,...
            % FirstRate,33750.300000,37511.600000,...
            % LastRate,33990.600000,37233.700000,...
            % Realtime,14.912900,34.667900,...
            % VacPressure,1022,1023,...
            % AmbientPressure,1022,1023,...
            % ProbeTemp,25,30,...
            % TubeCurrentSet,32.832800,32.832800,...
            % TubeCurrentMon,33.288600,33.020100,...
            % TubeVoltageSet,40.000000,40.000000,...
            % TubeVoltageMon,39.946300,40.000000,...
            % LTMult,1.000000,1.000000,...
            % ColMult,1.000000,1.000000,...
            % FilterPosition,1,1,...
            % TimeStamp,2018-09-10 13:07:38,2018-09-10 14:24:43,...
            % ,0,0,...
            % ,0,0,...
            % ,0,0,...
            % ...
            % ,22,53,...
            % ,23,68,...
            
            firstTime = true;
            chanNum = 0;
            InnovXSpec = struct;
            % Read the first lineIn
            txtLine = fgetl(fID);
            sep = {',',char(9)};
            while ischar(txtLine)
                % Split this line
                txtParts = split(txtLine,sep);
                if firstTime
                    % find out how many spectra we have
                    app.numSpec = numel(txtParts)-1;
                    % Store the data in the firts field "TestID"
                    for n=1:app.numSpec
                        InnovXSpec(n).(txtParts{1}) = txtParts{n+1};
                    end
                    firstTime = false; % now we know enough
                elseif strcmp(txtParts{1},'NumData')
                    % If we get to the line "NumData" we know how many channels we have
                    for n=1:app.numSpec
                        % Maybe not all spectra have the same number of channels?
                        numChan = str2double(txtParts(n+1));
                        if numChan > app.maxChan
                            app.maxChan = numChan;
                        end
                        InnovXSpec(n).(txtParts{1}) = numChan;
                        % create an spectrum array
                        InnovXSpec(n).Y = zeros(numChan,1);
                    end
                elseif strcmp(txtParts{1},'TimeStamp')
                    % Treat this line separately because is the only containing text
                    for n=1:app.numSpec
                        InnovXSpec(n).(txtParts{1}) = txtParts{n+1};
                    end
                elseif isempty(deblank(txtParts{1}))
                    % an empty first column means we have now spectral data
                    chanNum = chanNum+1;
                    for n=1:app.numSpec
                        InnovXSpec(n).Y(chanNum) = str2double(txtParts{n+1});
                    end
                else
                    % All other fields we save in the structure with the same name
                    for n=1:app.numSpec
                        InnovXSpec(n).(txtParts{1}) = str2double(txtParts{n+1});
                    end
                end
                % and get the next line
                txtLine = fgetl(fID);
            end
            
            fclose(fID);
            app.Spectra = InnovXSpec;
        end
        
        function plotSpectra(app)
            tableData = app.UISpecTable.Data;
            KeVScale = false;
            if strcmp(app.XScaleSwitch.Value,'keV')
                KeVScale = true;
            end
            xmin = 1e6;
            xmax = -1e6;
            for n=1:app.numSpec
                if tableData{n,2}
                    numChan = app.Spectra(n).NumData;
                    X = 1:numChan;
                    if KeVScale
                        offset = app.Spectra(n).Offset;
                        slope = app.Spectra(n).Slope;
                        X = offset + slope*X;
                    end
                    plot(app.UIAxes,X,app.Spectra(n).Y);
                    xmin = min(X);
                    xmax = max(X);
                    upDateInfo(app,app.Spectra(n));
                end
            end
            if KeVScale
                app.UIAxes.XLabel.String = 'Energy keV';
            else
                app.UIAxes.XLabel.String = 'Channel #';                
            end
            app.UIAxes.YScale = app.YScaleSwitch.Value;
            app.UIAxes.YLabel.String = 'Counts';
            if xmax > xmin
                app.UIAxes.XLim = [xmin, xmax];
            end
        end
        
        function result = saveAsTxt(app,fullFileName,n)
            fID = fopen(fullFileName,"wt");
            if fID == -1
                disp("Cannot open file");
                result = false;
                return;
            end
            spec = app.Spectra(n);
            for ch=1:spec.NumData
                fprintf(fID,"%d\n",spec.Y(ch));
            end
            fclose(fID);
            result = true;
        end
        
        function result = saveAsAXML(app,fullFileName,n)
            spec = app.Spectra(n);
            doc = com.mathworks.xml.XMLUtils.createDocument('bAxil');
            root = doc.getDocumentElement;
            spectralDataNode = makeSpectralDataNode(doc);
                firstChan = 0;
                lastChan = spec.NumData;
                Y = spec.Y;
                contentNode = makeContentNode(doc,firstChan,lastChan,Y);
                spectralDataNode.appendChild(contentNode);
                liveTimeNode = makeLiveTimeNode(doc,spec.Livetime);
                spectralDataNode.appendChild(liveTimeNode);
                realTimeNode = makeRealTimeNode(doc,spec.Realtime);
                spectralDataNode.appendChild(realTimeNode);
                sampleIDNode = makeSampleIDNode(doc,spec.TestID);
                spectralDataNode.appendChild(sampleIDNode);
                measStartDate = makeMeasStartDateNode(doc,spec.TimeStamp);
                spectralDataNode.appendChild(measStartDate);
                measStartTime = makeMeasStartTimeNode(doc,spec.TimeStamp);
                spectralDataNode.appendChild(measStartTime);                
            root.appendChild(spectralDataNode);
            modelNode = makeModelNode(doc);
                calibrationNode = makeCalibrationNode(doc,spec.Offset,spec.Slope);
                modelNode.appendChild(calibrationNode);
            root.appendChild(modelNode);    
                
            xmlwrite(fullFileName,doc);
            result = true;
            function newNode = makeSpectralDataNode(doc)
                newNode = doc.createElement('SpectralData');
            end
            function newNode = makeContentNode(doc,firstChan,lastChan,Y)
                newNode = doc.createElement('Content');
                newNode.setAttribute('FirstChan',compose('%d',firstChan));
                newNode.setAttribute('LastChan',compose('%d',lastChan));
                dataNode = doc.createTextNode(sprintf('%d ',Y));
                newNode.appendChild(dataNode);        
            end
            function newNode = makeLiveTimeNode(doc,liveTime)
                newNode = doc.createElement('LiveTime');
                dataNode = doc.createTextNode(sprintf('%.3f',liveTime));
                newNode.appendChild(dataNode);
            end
            function newNode = makeRealTimeNode(doc,realTime)
                newNode = doc.createElement('RealTime');
                dataNode = doc.createTextNode(sprintf('%.3f',realTime));
                newNode.appendChild(dataNode);
            end
            function newNode = makeSampleIDNode(doc,sampleID)
                newNode = doc.createElement('SampleID');
                dataNode = doc.createTextNode(sampleID);
                newNode.appendChild(dataNode);
            end
            function newNode = makeMeasStartDateNode(doc,dateTimeString)
                newNode = doc.createElement('MeasurementStartDate');
                value = sscanf(dateTimeString,'%d-%d-%d %d:%d:%d');
                dataNode = doc.createTextNode(sprintf(' %d %d %d ',value(3),value(2),value(1)));
                newNode.appendChild(dataNode);
            end
            function newNode = makeMeasStartTimeNode(doc,dateTimeString)
                newNode = doc.createElement('MeasurementStartTime');
                value = sscanf(dateTimeString,'%d-%d-%d %d:%d:%d');
                dataNode = doc.createTextNode(sprintf(' %d %d %d ',value(4),value(5),value(6)));
                newNode.appendChild(dataNode);
            end
            function newNode = makeModelNode(doc)
                newNode = doc.createElement('Model');
            end
            function newNode = makeCalibrationNode(doc,offset,slope)
                newNode = doc.createElement('Calibration');
                zeroNode = makeCalParamNode(doc,'Zero',offset,0.1,offset,0.1,0.);
                newNode.appendChild(zeroNode);
                gainNode = makeCalParamNode(doc,'Gain',slope,0.02,slope,0.02,0.);
                newNode.appendChild(gainNode);
            end
            function newNode = makeCalParamNode(doc,nodeName,init,initConst,fitted,fittedConst,Std)
                newNode = doc.createElement(nodeName);
                initialNode = doc.createElement('Initial');
                initialValueNode = doc.createTextNode(sprintf('%.5E',init));
                initialNode.appendChild(initialValueNode);
                newNode.appendChild(initialNode);

                initialConstraintNode = doc.createElement('InitialConstraint');
                initialConstraintValueNode = doc.createTextNode(sprintf('%.5E',initConst));
                initialConstraintNode.appendChild(initialConstraintValueNode);
                newNode.appendChild(initialConstraintNode);

                fittedNode = doc.createElement('Fitted');
                fittedValueNode = doc.createTextNode(sprintf('%.5E',fitted));
                fittedNode.appendChild(fittedValueNode);
                newNode.appendChild(fittedNode);

                fittedConstraintNode = doc.createElement('FittedConstraint');
                fittedConstraintValueNode = doc.createTextNode(sprintf('%.5E',fittedConst));
                fittedConstraintNode.appendChild(fittedConstraintValueNode);
                newNode.appendChild(fittedConstraintNode);

                standardDeviationNode = doc.createElement('StandardDeviation');
                standardDeviationValueNode = doc.createTextNode(sprintf('%.5E',Std));
                standardDeviationNode.appendChild(standardDeviationValueNode);
                newNode.appendChild(standardDeviationNode);

            end
        end
        
        function result = saveAsSPE(app,fullFileName,n)
            fID = fopen(fullFileName,"wt");
            if fID == -1
                disp("Cannot open file");
                result = false;
                return;
            end
            spec = app.Spectra(n);
            fprintf(fID,'$SPEC_ID:\n');
            fprintf(fID,'%s\n',spec.('TestID'));
            fprintf(fID,'$MEAS_Time:\n');
            fprintf(fID,'%.2f %.2f\n',spec.('Livetime'),spec.('Realtime'));
            fprintf(fID,'$TUBE_CUR:\n');
            fprintf(fID,'%.4E\n',spec.('TubeCurrentSet'));
            fprintf(fID,'$ACC_VOLT:\n');
            fprintf(fID,'%.4E\n',spec.('TubeVoltageSet'));
            % time stamp is:  '2019-11-14 14:49:04'
            timestamp = sscanf(spec.('TimeStamp'),'%4d-%2d-%2d %2d:%2d:%2d');
            fprintf(fID,'$DATE_MEA:\n');
            fprintf(fID,'%02d-%02d-%4d %02d:%02d:%02d\n',...
                timestamp(3),timestamp(2),timestamp(1),...
                timestamp(4),timestamp(5),timestamp(6));
            fprintf(fID,'$ENERGY_CAL:\n');
            fprintf(fID,'%.4E %.4E\n',spec.('Offset'),spec.('Slope'));
            Y = spec.('Y');
            fprintf(fID,'$DATA:\n');
            fprintf(fID,'0 %d\n',spec.('NumData')-1);
            for n=1:spec.('NumData')
                fprintf(fID,'%d',Y(n));
                if mod(n,5) == 0
                    fprintf(fID,'\n');
                else
                    fprintf(fID,' ');
                end
            end
            if mod(n,5) ~= 0
                fprintf(fID,'\n');
            end
            fclose(fID);
            result = true;
            
        end
        
        function upDateInfo(app,spec)
            infoData = cell(20,2);
            fn = fieldnames(spec);
            row = 0;
            for n=1:21
                if strcmp(fn{n},'Y')
                    continue;
                end
                row = row+1;
                infoData{row,1} = fn{n};
                infoData{row,2} = char(string(spec.(fn{n})));
            end           
            app.UIInfoTable.Data = infoData;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.maxChan = 0;
            app.numSpec = 0;
            app.SaveMenu.Enable = false;
            app.SaveAllMenu.Enable = false;
            app.XScaleSwitch.Value = 'keV';
            app.NumberofSpectraEditField.Value = app.numSpec;
            app.UIInfoTable.ColumnName = {'Param','Value'};
            app.UIInfoTable.ColumnFormat = {'char', 'char'};
            app.UIInfoTable.ColumnEditable = [false false];

            app.UISpecTable.ColumnName = {'Spectrum','Plot'};
            app.UISpecTable.ColumnFormat = {'char','logical'};
            app.UISpecTable.ColumnEditable = [false true];

        end

        % Menu selected function: InnovXCSVMenu
        function OpenInnovXCSV(app, event)
            [fileName,pathName]=uigetfile({'*.CSV';'*.csv'},'Open Innov-X CSV file');
            fullFileName = strcat(pathName,fileName);
            if readInnovXCSV(app,fullFileName)
                app.UIFigure.Name = fileName;
                InitControls(app);
                plotSpectra(app);
            end
        end

        % Cell edit callback: UISpecTable
        function UISpecTableCellEdit(app, event)
            %indices = event.Indices;
            %newData = event.NewData;
            col = event.Indices(2);
            if col == 2
                tableData = app.UISpecTable.Data;
                row = event.Indices(1);
                for n=1:app.numSpec
                    if tableData{n,2}
                        tableData{n,2} = false;
                    end
                end
                tableData{row,2} = true;
                app.UISpecTable.Data = tableData;
                plotSpectra(app);
            end
        end

        % Value changed function: YScaleSwitch
        function YScaleSwitchValueChanged(app, event)
            %value = app.YScaleSwitch.Value;
            plotSpectra(app);
        end

        % Value changed function: XScaleSwitch
        function XScaleSwitchValueChanged(app, event)
            %value = app.XScaleSwitch.Value;
            plotSpectra(app);
        end

        % Menu selected function: SaveAllMenu
        function SaveAllMenuSelected(app, event)
            % Save all spectra
            title = 'Save all spectrum';
            filter = {'*.axml','standard bAxil file (*.axml)'; ...
                '*.spe','Axil SPE file (*.spe)';'*.txt','General text file (*.txt)'};
            tableData = app.UISpecTable.Data;
            defname = tableData{1,1};
            [file,path,filterIdx] = uiputfile(filter,title,defname);
            if isequal(file,0) && isequal(path,0)
                return; % User pressed Cancel
            end
            userDefName = false;
            parts = split(file,'.');
            baseName = parts{1};
            if ~strcmpi(defname,baseName) %user changed the file name
                userDefName = true;
            end
            for n=1:app.numSpec
                if userDefName
                    file = sprintf('%s_%d',baseName,n);
                else
                    file = tableData{n,1};
                end
                switch(filterIdx)
                    case 1
                        fullFileName = strcat(path,file,'.axml');
                        saveAsAXML(app,fullFileName,n);
                    case 2
                        fullFileName = strcat(path,file,'.spe');
                        saveAsSPE(app,fullFileName,n);
                    case 3
                        fullFileName = strcat(path,file,'.txt');
                        saveAsTxt(app,fullFileName,n);
                end
            end
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            % Save the current displayed file
            % Only one spectrum can be displayed at any time
            % After saving the spectra the next spectra if any is plotted
            % an thus ready for saving
            title = 'Save currectly plotted spectrum';
            filter = {'*.axml','standard bAxil file (*.axml)'; ...
                '*.spe','Axil SPE file (*.spe)';'*.txt','General text file (*.txt)'};
            tableData = app.UISpecTable.Data;
            for n=1:app.numSpec
                if tableData{n,2}
                    defname = tableData{n,1};
                    [file,path,filterIdx] = uiputfile(filter,title,defname);
                    if isequal(file,0) && isequal(path,0)
                        return; % User pressed Cancel
                    end
                    fullFileName = strcat(path,file);
                    switch(filterIdx)
                        case 1
                            saveAsAXML(app,fullFileName,n);
                        case 2
                            saveAsSPE(app,fullFileName,n);
                        case 3
                            saveAsTxt(app,fullFileName,n);
                    end
               end
            end       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Text = 'Open';
            app.InnovXCSVMenu = uimenu(app.OpenMenu);
            app.InnovXCSVMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenInnovXCSV, true);
            app.InnovXCSVMenu.Text = 'InnovX CSV';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save...';
            app.SaveAllMenu = uimenu(app.FileMenu);
            app.SaveAllMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAllMenuSelected, true);
            app.SaveAllMenu.Text = 'Save All ...';
            app.NumberofSpectraEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofSpectraEditFieldLabel.Text = 'Number of Spectra';
            app.NumberofSpectraEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofSpectraEditField.ValueDisplayFormat = '%.0f';
            app.NumberofSpectraEditField.Editable = 'off';
            app.UISpecTable = uitable(app.UIFigure);
            app.UISpecTable.ColumnName = {'Spectrum'; 'Select'};
            app.UISpecTable.RowName = {};
            app.UISpecTable.CellEditCallback = createCallbackFcn(app, @UISpecTableCellEdit, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Channel #')
            ylabel(app.UIAxes, 'Counts')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.XScaleSwitch = uiswitch(app.UIFigure, 'slider');
            app.XScaleSwitch.Items = {'Chan', 'keV'};
            app.XScaleSwitch.ValueChangedFcn = createCallbackFcn(app, @XScaleSwitchValueChanged, true);
            app.XScaleSwitch.Value = 'Chan';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.YScaleSwitch = uiswitch(app.UIFigure, 'slider');
            app.YScaleSwitch.Items = {'Lin', 'Log'};
            app.YScaleSwitch.ValueChangedFcn = createCallbackFcn(app, @YScaleSwitchValueChanged, true);
            app.YScaleSwitch.Value = 'Lin';
            app.UIInfoTable = uitable(app.UIFigure);
            app.UIInfoTable.ColumnName = {'Param'; 'value'};
            app.UIInfoTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PXRF2bAxil

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end